classdef mels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DevelopedbyRommelJohnHaliliRonduenLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ExplanationLabel              matlab.ui.control.Label
        ADiscreteTimeCalculatorLabel  matlab.ui.control.Label
        StabilityDetermination        matlab.ui.control.Button
        InvarianceDetermination       matlab.ui.control.Button
        LinearityDetermination        matlab.ui.control.Button
        CausalityDetermination        matlab.ui.control.Button
        EnergyDetermination           matlab.ui.control.Button
        StaticDetermination           matlab.ui.control.Button
        Output                        matlab.ui.control.TextArea
        ClassificationTextAreaLabel   matlab.ui.control.Label
        InputBox                      matlab.ui.control.TextArea
        SysteminputLabel              matlab.ui.control.Label
    end

        
        methods (Access = public)
            
            function signal = getInputSignal(app)
    try
        % Convert input to string and trim spaces
        inputSignal = strtrim(strjoin(app.InputBox.Value, ' '));

        % Ensure input is not empty
        if isempty(inputSignal)
            app.Output.Value = 'Error: Empty input';
            signal = [];
            return;
        end

        % Check if input follows 'y(n) = expression' format
        inputPattern = '(y|x)\(n\)\s*=\s*(.*)'; % Regex to capture expression
        tokens = regexp(inputSignal, inputPattern, 'tokens');

        if ~isempty(tokens)
            inputSignal = strtrim(tokens{1}{2}); % Extract the expression
        else
            app.Output.Value = 'Error: Use "y(n) = ..." or "x(n) = ..."';
            signal = [];
            return;
        end

        % Define symbolic variables properly
        syms x(n) n;

        % Convert input expression to symbolic (handle element-wise operations)
        inputSignal = strrep(inputSignal, '^', '.^');
        inputSignal = strrep(inputSignal, '*', '.*');
        inputSignal = strrep(inputSignal, '/', './');
        signal = str2sym(inputSignal);
        
    catch ME
        app.Output.Value = ['Error: ', ME.message];
        app.ExplanationLabel.Text = ['Input Parsing Error: ', ME.message];
        signal = [];
    end
end
        end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StaticDetermination
        function StaticDeterminationPushed(app, event)
    signal = app.getInputSignal();
    explanation = {'Static/Dynamic Test Steps:', '----------------------'};
    if isempty(signal)
        return;
    end
    
    expr = char(signal);
    explanation{end+1} = ['Input expression: ', expr];
    
    % Check for arguments like x(n±k)
    tokens = regexp(expr, 'x\(([^)]+)\)', 'tokens');
    if isempty(tokens)
        explanation{end+1} = '1. No input terms (x) detected.';
        explanation{end+1} = '2. Conclusion: Static (no input dependency).';
        app.Output.Value = 'Static';
    else
        x_arg = strtrim(tokens{1}{1});
        if strcmp(x_arg, 'n')
            explanation{end+1} = '1. Detected current input term x(n).';
            explanation{end+1} = '2. Conclusion: Static (depends only on current input).';
            app.Output.Value = 'Static';
        else
            explanation{end+1} = ['1. Detected input term with argument: x(', x_arg, ')'];
            explanation{end+1} = '2. Conclusion: Dynamic (depends on past/future inputs).';
            app.Output.Value = 'Dynamic';
        end
    end
    
    app.ExplanationLabel.Text = strjoin(explanation, newline);
        end

        % Button pushed function: EnergyDetermination
        function EnergyDeterminationPushed(app, event)
            signal = app.getInputSignal();
            explanation = {'Energy/Power Test Steps:', '-----------------------'};
            if isempty(signal)
                return;
            end
            
            syms n integer;
            syms x(n);
            
            try
                % Compute impulse response h(n)
                delta(n) = kroneckerDelta(n, 0);
                h(n) = subs(signal, x(n), delta(n));
                explanation{end+1} = '1. Impulse response h(n):';
                explanation{end+1} = char(h(n));
                
                % Numerically evaluate energy
                N = 1000;
                n_values = -N:N;
                h_values = double(subs(h(n), n, n_values));
                energy = sum(abs(h_values).^2);
                explanation{end+1} = sprintf('2. Energy over n=%d to %d: %g', -N, N, energy);
                
                if energy < Inf
                    app.Output.Value = 'Energy Signal';
                    explanation{end+1} = '3. Finite energy → Energy Signal.';
                else
                    % Compute average power
                    N_power = 10000;
                    power = sum(abs(h_values).^2) / (2*N + 1);
                    app.Output.Value = 'Power Signal';
                    explanation{end+1} = sprintf('3. Average power: %.4f → Power Signal.', power);
                end
                
            catch ME
                app.Output.Value = ['Error: ', ME.message];
                explanation{end+1} = ['Error: ', ME.message];
            end
            
            app.ExplanationLabel.Text = strjoin(explanation, newline);
        end

        % Button pushed function: CausalityDetermination
        function CausalityDeterminationPushed(app, event)

    signal = app.getInputSignal();
    explanation = {'Causality Test Steps:', '-------------------'};
    if isempty(signal)
        return;
    end
    
    expr = char(signal);
    explanation{end+1} = ['Processing expression: ', expr];
    
    % Detect future-dependent terms (e.g., x(n+1))
    antiCausalTerms = regexp(expr, 'x\s*\(\s*n\s*\+\s*\d+\s*\)', 'match');
    
    if ~isempty(antiCausalTerms)
        app.Output.Value = 'Anti-Causal';
        explanation{end+1} = '1. Detected future-dependent terms:';
        explanation{end+1} = strjoin(antiCausalTerms, ', ');
        explanation{end+1} = '2. Conclusion: Depends on future inputs → Anti-Causal.';
    else
        app.Output.Value = 'Causal';
        explanation{end+1} = '1. No future-dependent terms (e.g., x(n+k)) found.';
        explanation{end+1} = '2. Conclusion: Depends on current/past inputs → Causal.';
    end
    
    app.ExplanationLabel.Text = strjoin(explanation, newline);
        end

        % Button pushed function: LinearityDetermination
        function LinearityDeterminationPushed(app, event)
            signal = app.getInputSignal();
            explanation = {'Linearity Test Steps:', '-------------------'};
            if isempty(signal)
                return;
            end
            
            syms n integer;
            syms x(n) x1(n) x2(n) a b;
            
            try
                % Substitute x(n) with a*x1(n) + b*x2(n)
                T_ax1_bx2 = subs(signal, x, a*x1 + b*x2);
                explanation{end+1} = '1. Compute T(a*x1(n) + b*x2(n)):';
                explanation{end+1} = char(T_ax1_bx2);
                
                % Compute a*T(x1(n)) + b*T(x2(n))
                T_x1 = subs(signal, x, x1);
                T_x2 = subs(signal, x, x2);
                aT_plus_bT = a*T_x1 + b*T_x2;
                explanation{end+1} = '2. Compute a*T(x1(n)) + b*T(x2(n)):';
                explanation{end+1} = char(aT_plus_bT);
                
                % Check equality
                isLinear = isAlways(T_ax1_bx2 == aT_plus_bT);
                if all(isLinear)
                    app.Output.Value = 'Linear';
                    explanation{end+1} = '3. T(a*x1 + b*x2) ≡ a*T(x1) + b*T(x2) → Linear.';
                else
                    app.Output.Value = 'Non-Linear';
                    explanation{end+1} = '3. T(a*x1 + b*x2) ≠ a*T(x1) + b*T(x2) → Non-Linear.';
                end
                
            catch ME
                app.Output.Value = ['Error: ', ME.message];
                explanation{end+1} = ['Error: ', ME.message];
            end
            
            app.ExplanationLabel.Text = strjoin(explanation, newline);
        end

        % Button pushed function: InvarianceDetermination
        function InvarianceDeterminationPushed(app, event)
                signal = app.getInputSignal();
                explanation = {'Time Invariance Test Steps:', '---------------------------'};
                if isempty(signal)
                    return;
                end
                
                syms n k integer;
                syms x(n);
                
                try
                    % Compute T[x(n - k)] and y(n - k)
                    x_shift(n) = x(n - k);
                    T_x_shift = subs(signal, x(n), x_shift);
                    y_shifted = subs(signal, n, n - k);
                    
                    explanation{end+1} = '1. Compute T[x(n - k)]:';
                    explanation{end+1} = char(T_x_shift);
                    explanation{end+1} = '2. Compute y(n - k):';
                    explanation{end+1} = char(y_shifted);
                    
                    % Check equivalence
                    isInvariant = logical(isAlways(T_x_shift == y_shifted, 'Unknown', 'false'));
                    if isInvariant
                        app.Output.Value = 'Time Invariant';
                        explanation{end+1} = '3. T[x(n - k)] ≡ y(n - k) → Time Invariant.';
                    else
                        app.Output.Value = 'Time Variant';
                        explanation{end+1} = '3. T[x(n - k)] ≠ y(n - k) → Time Variant.';
                    end
                    
                catch ME
                    app.Output.Value = ['Error: ', ME.message];
                    explanation{end+1} = ['Error: ', ME.message];
                end
                
                app.ExplanationLabel.Text = strjoin(explanation, newline);

        end

        % Button pushed function: StabilityDetermination
        function StabilityDeterminationPushed(app, event)
    signal = app.getInputSignal();
    explanation = {'Stability Test Steps:', '-------------------'};
    if isempty(signal)
        return;
    end
    
    expr = char(signal);
    explanation{end+1} = ['Input expression: ', expr];
    
    % 1. Check for terms like n*x(n) or n^k (explicitly unbounded)
    unstableTerms = regexp(expr, '(\bn\s*\.?\*\s*x\(n\)|x\(n\)\s*\.?\*\s*\bn|n\s*\^\d+)', 'match');
    if ~isempty(unstableTerms)
        app.Output.Value = 'Unstable';
        explanation{end+1} = '1. Detected unbounded terms:';
        explanation{end+1} = strjoin(unstableTerms, ', ');
        explanation{end+1} = '2. Terms grow with n → Unstable.';
    else
        explanation{end+1} = '1. No explicit unstable terms (e.g., n*x(n)).';
        explanation{end+1} = '2. Testing BIBO stability via impulse response...';
        
        try
            syms n integer;
            delta(n) = kroneckerDelta(n, 0);
            
            % Compute impulse response h(n) = T[δ(n)]
            h(n) = subs(signal, x(n), delta(n));
            explanation{end+1} = '3. Impulse response h(n):';
            explanation{end+1} = char(h(n));
            
            % Numerically check absolute summability of h(n)
            N = 10000;  % Large enough to approximate infinity
            n_values = -N:N;
            h_values = double(subs(h(n), n, n_values));
            
            if any(isnan(h_values)) || any(isinf(h_values))
                error('Impulse response contains NaN/Inf values.');
            end
            
            sum_abs_h = sum(abs(h_values));
            explanation{end+1} = sprintf('4. Sum of |h(n)| from n=%d to %d: %g', -N, N, sum_abs_h);
            
            if isfinite(sum_abs_h) && sum_abs_h < Inf
                app.Output.Value = 'Stable';
                explanation{end+1} = '5. Impulse response is absolutely summable → BIBO Stable.';
            else
                app.Output.Value = 'Unstable';
                explanation{end+1} = '5. Impulse response is NOT absolutely summable → Unstable.';
            end
            
        catch ME
            app.Output.Value = 'Stability Test Error';
            explanation{end+1} = ['Error: ', ME.message];
        end
    end
    
    app.ExplanationLabel.Text = strjoin(explanation, newline);
        end

        % Callback function
        function InputTextAreaValueChanged(app, event)
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SysteminputLabel = uilabel(app.UIFigure);
            app.SysteminputLabel.Text = 'System input';
            app.InputBox = uitextarea(app.UIFigure);
            app.ClassificationTextAreaLabel = uilabel(app.UIFigure);
            app.ClassificationTextAreaLabel.Text = 'Classification';
            app.Output = uitextarea(app.UIFigure);
            app.StaticDetermination = uibutton(app.UIFigure, 'push');
            app.StaticDetermination.ButtonPushedFcn = createCallbackFcn(app, @StaticDeterminationPushed, true);
            app.StaticDetermination.Text = 'Static test';
            app.EnergyDetermination = uibutton(app.UIFigure, 'push');
            app.EnergyDetermination.ButtonPushedFcn = createCallbackFcn(app, @EnergyDeterminationPushed, true);
            app.EnergyDetermination.Text = 'Energy test';
            app.CausalityDetermination = uibutton(app.UIFigure, 'push');
            app.CausalityDetermination.ButtonPushedFcn = createCallbackFcn(app, @CausalityDeterminationPushed, true);
            app.CausalityDetermination.Text = 'Causality test';
            app.LinearityDetermination = uibutton(app.UIFigure, 'push');
            app.LinearityDetermination.ButtonPushedFcn = createCallbackFcn(app, @LinearityDeterminationPushed, true);
            app.LinearityDetermination.Text = 'Linearity test';
            app.InvarianceDetermination = uibutton(app.UIFigure, 'push');
            app.InvarianceDetermination.ButtonPushedFcn = createCallbackFcn(app, @InvarianceDeterminationPushed, true);
            app.InvarianceDetermination.Text = 'Invariance test';
            app.StabilityDetermination = uibutton(app.UIFigure, 'push');
            app.StabilityDetermination.ButtonPushedFcn = createCallbackFcn(app, @StabilityDeterminationPushed, true);
            app.StabilityDetermination.Text = 'Stability test';
            app.ADiscreteTimeCalculatorLabel = uilabel(app.UIFigure);
            app.ADiscreteTimeCalculatorLabel.Text = 'A Discrete-Time Calculator';
            app.ExplanationLabel = uilabel(app.UIFigure);
            app.ExplanationLabel.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'logo.png');
            app.DevelopedbyRommelJohnHaliliRonduenLabel = uilabel(app.UIFigure);
            app.DevelopedbyRommelJohnHaliliRonduenLabel.Text = 'Developed by Rommel John Halili Ronduen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mels

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end